classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        PropertiesTab         matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        Angle2EditField       matlab.ui.control.NumericEditField
        Angle2Label           matlab.ui.control.Label
        Angle1EditField       matlab.ui.control.NumericEditField
        Angle1EditFieldLabel  matlab.ui.control.Label
        ZEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        Tree                  matlab.ui.container.Tree
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'equal');            
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            currentPoint = app.UIAxes.CurrentPoint;
            plot3(app.UIAxes, currentPoint(1),currentPoint(2),currentPoint(3),'LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b', 'Marker','+');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Tree = uitree(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout2 = uigridlayout(app.PropertiesTab);
            app.XEditFieldLabel = uilabel(app.GridLayout2);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout2);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditFieldLabel = uilabel(app.GridLayout2);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Angle1EditFieldLabel = uilabel(app.GridLayout2);
            app.Angle1EditFieldLabel.Text = 'Angle 1';
            app.Angle1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Angle2Label = uilabel(app.GridLayout2);
            app.Angle2Label.Text = 'Angle 2';
            app.Angle2EditField = uieditfield(app.GridLayout2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end